classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Urban5GRXUIFigure            matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TXmodelsPanel                matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        UMaLabel                     matlab.ui.control.Label
        FrequencyHzLabel             matlab.ui.control.Label
        uma_frequency                matlab.ui.control.NumericEditField
        PowerWEditFieldLabel         matlab.ui.control.Label
        uma_power                    matlab.ui.control.NumericEditField
        PowerWEditField_2Label       matlab.ui.control.Label
        umi_coverage_power           matlab.ui.control.NumericEditField
        FrequencyHzEditField_3Label  matlab.ui.control.Label
        umi_hotspot_frequency        matlab.ui.control.NumericEditField
        PowerWEditField_3Label       matlab.ui.control.Label
        umi_hotspot_power            matlab.ui.control.NumericEditField
        UMiGridLabel                 matlab.ui.control.Label
        UMiHotspotLabel              matlab.ui.control.Label
        PowerWEditField_4Label       matlab.ui.control.Label
        umi_blind_power              matlab.ui.control.NumericEditField
        FrequencyHzEditField_4Label  matlab.ui.control.Label
        umi_blind_frequency          matlab.ui.control.NumericEditField
        UMiBlindCoverageLabel        matlab.ui.control.Label
        FrequencyHzEditField_2Label  matlab.ui.control.Label
        umi_coverage_frequency       matlab.ui.control.NumericEditField
        MapandmodePanel              matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        MinimumLatitudeLabel         matlab.ui.control.Label
        minimum_latitude             matlab.ui.control.NumericEditField
        MaximumLatitudeLabel         matlab.ui.control.Label
        maximum_latitude             matlab.ui.control.NumericEditField
        MinimumLongitudeLabel        matlab.ui.control.Label
        minimum_longitude            matlab.ui.control.NumericEditField
        MaximumLongitudeLabel        matlab.ui.control.Label
        maximum_longitude            matlab.ui.control.NumericEditField
        ModeButtonGroup              matlab.ui.container.ButtonGroup
        capacity                     matlab.ui.control.RadioButton
        coverage                     matlab.ui.control.RadioButton
        MaxattemptsforcoveragemodeEditFieldLabel  matlab.ui.control.Label
        max_attempts                 matlab.ui.control.NumericEditField
        download_map_file            matlab.ui.control.CheckBox
        CompaniesandusersPanel       matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        filter_by_company            matlab.ui.control.CheckBox
        NumberofreceiversPanel       matlab.ui.container.Panel
        number_of_receivers          matlab.ui.control.NumericEditField
        IndividualBWMHzPanel         matlab.ui.container.Panel
        RandomDistributionofReceiversCheckBox  matlab.ui.control.CheckBox
        individual_bw                matlab.ui.control.NumericEditField
        country                      matlab.ui.control.DropDown
        companyAustralia             matlab.ui.control.DropDown
        companySpain                 matlab.ui.control.DropDown
        STARTButton                  matlab.ui.control.Button
        MiscPanel                    matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        PropagationmodelButtonGroup  matlab.ui.container.ButtonGroup
        longley_rice                 matlab.ui.control.RadioButton
        ray_tracing                  matlab.ui.control.RadioButton
        UMiIntersitedistmPanel       matlab.ui.container.Panel
        umi_coverage_isd             matlab.ui.control.NumericEditField
        SimulationRxPanel            matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        CSVoXLXSButton               matlab.ui.control.Button
        DistancePanel                matlab.ui.container.Panel
        distance                     matlab.ui.control.NumericEditField
        DurationPanel                matlab.ui.container.Panel
        duration                     matlab.ui.control.NumericEditField
        SpeedPanel                   matlab.ui.container.Panel
        fileName                     matlab.ui.control.Label
        FileLabel                    matlab.ui.control.Label
        speed                        matlab.ui.control.NumericEditField
        GridLayout7                  matlab.ui.container.GridLayout
        TotalTimePanel               matlab.ui.container.Panel
        StepPanel                    matlab.ui.container.Panel
        period                       matlab.ui.control.NumericEditField
        step                         matlab.ui.control.NumericEditField
        MobilityAnalysisLabel        matlab.ui.control.Label
        DeploymentPanel              matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        UMACheckBox                  matlab.ui.control.CheckBox
        UMIGridCheckBox              matlab.ui.control.CheckBox
        UMIHotspotCheckBox           matlab.ui.control.CheckBox
        UMIBlindCheckBox             matlab.ui.control.CheckBox
        FileNetworkCellInfoCheckBox  matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            addpath('entities');
            parameters = input_parameters();
            
            parameters.uma_frequency = app.uma_frequency.Value;
            parameters.uma_power = app.uma_power.Value;
            parameters.umi_coverage_power = app.umi_coverage_power.Value;
            parameters.umi_coverage_frequency = app.umi_coverage_frequency.Value;
            parameters.umi_hotspot_power = app.umi_hotspot_power.Value;
            parameters.umi_hotspot_frequency = app.umi_hotspot_frequency.Value;
            parameters.umi_blind_power = app.umi_blind_power.Value;
            parameters.umi_blind_frequency = app.umi_blind_frequency.Value;
            parameters.minimum_latitude = app.minimum_latitude.Value;
            parameters.maximum_latitude = app.maximum_latitude.Value;
            parameters.minimum_longitude = app.minimum_longitude.Value;
            parameters.maximum_longitude = app.maximum_longitude.Value;
            parameters.capacity = app.capacity.Value;
            parameters.coverage = app.coverage.Value;
            parameters.max_attempts = app.max_attempts.Value;
            parameters.download_map_file = app.download_map_file.Value;
            parameters.filter_by_company = app.filter_by_company.Value;
            parameters.country=app.country.Value;
            parameters.companyAustralia = app.companyAustralia.Value;
            parameters.companySpain = app.companySpain.Value;
            parameters.number_of_receivers = app.number_of_receivers.Value;
            parameters.longley_rice = app.longley_rice.Value;
            parameters.ray_tracing = app.ray_tracing.Value;
            parameters.umi_coverage_isd = app.umi_coverage_isd.Value;
            parameters.individual_bw = app.individual_bw.Value;
            parameters.total_time=app.period.Value;
            parameters.step=app.step.Value;
            parameters.distance=app.distance.Value;
            parameters.duration=app.duration.Value;
            parameters.speed=app.speed.Value;
            parameters.filename=app.fileName.Text;
            parameters.uma=app.UMACheckBox.Value;
            parameters.grid=app.UMIGridCheckBox.Value;
            parameters.hotspot=app.UMIHotspotCheckBox.Value;
            parameters.blind=app.UMIBlindCheckBox.Value;
            parameters.networkcellinfo=app.FileNetworkCellInfoCheckBox.Value;
            parameters.distributionReceivers=app.RandomDistributionofReceiversCheckBox.Value;
            request(parameters);
        end

        % Button pushed function: CSVoXLXSButton
        function CSVoXLXSButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.xlsx;*.csv*','Abre archivo csv o xlsx'});
            app.fileName.Text = fullfile(pathname,filename);
            
        end

        % Value changed function: companyAustralia
        function companyAustraliaValueChanged(app, event)
            
        end

        % Value changed function: country
        function countryValueChanged(app, event)
            value = app.country.Value;
            
            switch value
                case '214'
                    app.companyAustralia.Visible='off';
                    app.companySpain.Visible='on';
                case '505'
                    app.companySpain.Visible='off';
                    app.companyAustralia.Visible='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Urban5GRXUIFigure = uifigure('Visible', 'off');
            app.Urban5GRXUIFigure.Name = 'Urban 5GRX';
            app.GridLayout = uigridlayout(app.Urban5GRXUIFigure);
            app.TXmodelsPanel = uipanel(app.GridLayout);
            app.TXmodelsPanel.Title = 'TX models';
            app.GridLayout4 = uigridlayout(app.TXmodelsPanel);
            app.UMaLabel = uilabel(app.GridLayout4);
            app.UMaLabel.Text = 'UMa';
            app.FrequencyHzLabel = uilabel(app.GridLayout4);
            app.FrequencyHzLabel.Text = {'Frequency'; '(Hz)'};
            app.uma_frequency = uieditfield(app.GridLayout4, 'numeric');
            app.uma_frequency.Limits = [1 100000000000];
            app.uma_frequency.Value = 3500000000;
            app.PowerWEditFieldLabel = uilabel(app.GridLayout4);
            app.PowerWEditFieldLabel.Text = 'Power (W)';
            app.uma_power = uieditfield(app.GridLayout4, 'numeric');
            app.uma_power.Value = 25;
            app.PowerWEditField_2Label = uilabel(app.GridLayout4);
            app.PowerWEditField_2Label.Text = 'Power (W)';
            app.umi_coverage_power = uieditfield(app.GridLayout4, 'numeric');
            app.umi_coverage_power.Value = 5;
            app.FrequencyHzEditField_3Label = uilabel(app.GridLayout4);
            app.FrequencyHzEditField_3Label.Text = {'Frequency'; '(Hz)'};
            app.umi_hotspot_frequency = uieditfield(app.GridLayout4, 'numeric');
            app.umi_hotspot_frequency.Limits = [1 100000000000];
            app.umi_hotspot_frequency.Value = 18000000000;
            app.PowerWEditField_3Label = uilabel(app.GridLayout4);
            app.PowerWEditField_3Label.Text = 'Power (W)';
            app.umi_hotspot_power = uieditfield(app.GridLayout4, 'numeric');
            app.umi_hotspot_power.Value = 5;
            app.UMiGridLabel = uilabel(app.GridLayout4);
            app.UMiGridLabel.Text = 'UMi Grid';
            app.UMiHotspotLabel = uilabel(app.GridLayout4);
            app.UMiHotspotLabel.Text = 'UMi Hotspot';
            app.PowerWEditField_4Label = uilabel(app.GridLayout4);
            app.PowerWEditField_4Label.Text = 'Power (W)';
            app.umi_blind_power = uieditfield(app.GridLayout4, 'numeric');
            app.umi_blind_power.Value = 3;
            app.FrequencyHzEditField_4Label = uilabel(app.GridLayout4);
            app.FrequencyHzEditField_4Label.Text = {'Frequency'; '(Hz)'};
            app.umi_blind_frequency = uieditfield(app.GridLayout4, 'numeric');
            app.umi_blind_frequency.Limits = [1 100000000000];
            app.umi_blind_frequency.Value = 6000000000;
            app.UMiBlindCoverageLabel = uilabel(app.GridLayout4);
            app.UMiBlindCoverageLabel.Text = {'UMi Blind'; 'Coverage'};
            app.FrequencyHzEditField_2Label = uilabel(app.GridLayout4);
            app.FrequencyHzEditField_2Label.Text = {'Frequency'; '(Hz)'};
            app.umi_coverage_frequency = uieditfield(app.GridLayout4, 'numeric');
            app.umi_coverage_frequency.Limits = [1 100000000000];
            app.umi_coverage_frequency.Value = 16000000000;
            app.MapandmodePanel = uipanel(app.GridLayout);
            app.MapandmodePanel.Title = 'Map and mode';
            app.GridLayout2 = uigridlayout(app.MapandmodePanel);
            app.MinimumLatitudeLabel = uilabel(app.GridLayout2);
            app.MinimumLatitudeLabel.Text = 'Minimum Latitude';
            app.minimum_latitude = uieditfield(app.GridLayout2, 'numeric');
            app.minimum_latitude.ValueDisplayFormat = '%.4f';
            app.minimum_latitude.Value = 37.1602;
            app.MaximumLatitudeLabel = uilabel(app.GridLayout2);
            app.MaximumLatitudeLabel.Text = 'Maximum Latitude';
            app.maximum_latitude = uieditfield(app.GridLayout2, 'numeric');
            app.maximum_latitude.ValueDisplayFormat = '%.4f';
            app.maximum_latitude.Value = 37.1745;
            app.MinimumLongitudeLabel = uilabel(app.GridLayout2);
            app.MinimumLongitudeLabel.Text = {'Minimum'; 'Longitude'};
            app.minimum_longitude = uieditfield(app.GridLayout2, 'numeric');
            app.minimum_longitude.ValueDisplayFormat = '%.4f';
            app.minimum_longitude.Value = -3.61;
            app.MaximumLongitudeLabel = uilabel(app.GridLayout2);
            app.MaximumLongitudeLabel.Text = {'Maximum'; 'Longitude'};
            app.maximum_longitude = uieditfield(app.GridLayout2, 'numeric');
            app.maximum_longitude.ValueDisplayFormat = '%.4f';
            app.maximum_longitude.Value = -3.5875;
            app.ModeButtonGroup = uibuttongroup(app.GridLayout2);
            app.ModeButtonGroup.Title = 'Mode';
            app.capacity = uiradiobutton(app.ModeButtonGroup);
            app.capacity.Text = 'Capacity (focused on hotspots)';
            app.capacity.Value = true;
            app.coverage = uiradiobutton(app.ModeButtonGroup);
            app.coverage.Text = 'Coverage (try to cover everything)';
            app.MaxattemptsforcoveragemodeEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxattemptsforcoveragemodeEditFieldLabel.Text = {'Max attempts for'; 'coverage mode'};
            app.max_attempts = uieditfield(app.GridLayout2, 'numeric');
            app.max_attempts.Limits = [-1 Inf];
            app.max_attempts.RoundFractionalValues = 'on';
            app.max_attempts.ValueDisplayFormat = '%.0f';
            app.max_attempts.Value = 5;
            app.download_map_file = uicheckbox(app.GridLayout2);
            app.download_map_file.Text = 'Download map file';
            app.download_map_file.Value = true;
            app.CompaniesandusersPanel = uipanel(app.GridLayout);
            app.CompaniesandusersPanel.Title = 'Companies and users';
            app.GridLayout3 = uigridlayout(app.CompaniesandusersPanel);
            app.filter_by_company = uicheckbox(app.GridLayout3);
            app.filter_by_company.Text = 'Filter by company';
            app.filter_by_company.Value = true;
            app.NumberofreceiversPanel = uipanel(app.GridLayout3);
            app.NumberofreceiversPanel.Title = 'Number of receivers:';
            app.number_of_receivers = uieditfield(app.GridLayout3, 'numeric');
            app.number_of_receivers.Limits = [1 15000];
            app.number_of_receivers.ValueDisplayFormat = '%.0f';
            app.number_of_receivers.Value = 15000;
            app.IndividualBWMHzPanel = uipanel(app.GridLayout3);
            app.IndividualBWMHzPanel.Title = 'Individual BW (MHz):';
            app.RandomDistributionofReceiversCheckBox = uicheckbox(app.GridLayout3);
            app.RandomDistributionofReceiversCheckBox.Text = {'Random '; 'Distribution '; 'of Receivers'};
            app.individual_bw = uieditfield(app.GridLayout3, 'numeric');
            app.individual_bw.Limits = [0 10000];
            app.individual_bw.ValueDisplayFormat = '%.0f';
            app.individual_bw.Value = 100;
            app.country = uidropdown(app.GridLayout3);
            app.country.Items = {'Spain', 'Australia'};
            app.country.ItemsData = {'214', '505'};
            app.country.ValueChangedFcn = createCallbackFcn(app, @countryValueChanged, true);
            app.country.Value = '214';
            app.companyAustralia = uidropdown(app.GridLayout3);
            app.companyAustralia.Items = {'Telstra', 'Optus', 'Vodafone'};
            app.companyAustralia.ItemsData = {'1', '2', '3'};
            app.companyAustralia.ValueChangedFcn = createCallbackFcn(app, @companyAustraliaValueChanged, true);
            app.companyAustralia.Value = '1';
            app.companySpain = uidropdown(app.GridLayout3);
            app.companySpain.Items = {'Vodafone', 'Orange', 'Movistar'};
            app.companySpain.ItemsData = {'1', '3', '7'};
            app.companySpain.Value = '1';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START!';
            app.MiscPanel = uipanel(app.GridLayout);
            app.MiscPanel.Title = 'Misc.';
            app.GridLayout5 = uigridlayout(app.MiscPanel);
            app.PropagationmodelButtonGroup = uibuttongroup(app.GridLayout5);
            app.PropagationmodelButtonGroup.Title = 'Propagation model';
            app.longley_rice = uiradiobutton(app.PropagationmodelButtonGroup);
            app.longley_rice.Text = 'Longley-rice';
            app.longley_rice.Value = true;
            app.ray_tracing = uiradiobutton(app.PropagationmodelButtonGroup);
            app.ray_tracing.Text = 'Ray tracing';
            app.UMiIntersitedistmPanel = uipanel(app.GridLayout5);
            app.UMiIntersitedistmPanel.Title = 'UMi Intersite dist. (m)';
            app.umi_coverage_isd = uieditfield(app.GridLayout5, 'numeric');
            app.umi_coverage_isd.Limits = [1 1000];
            app.umi_coverage_isd.Value = 300;
            app.SimulationRxPanel = uipanel(app.GridLayout);
            app.SimulationRxPanel.Title = 'Simulation Rx';
            app.GridLayout6 = uigridlayout(app.SimulationRxPanel);
            app.CSVoXLXSButton = uibutton(app.GridLayout6, 'push');
            app.CSVoXLXSButton.ButtonPushedFcn = createCallbackFcn(app, @CSVoXLXSButtonPushed, true);
            app.CSVoXLXSButton.Text = 'CSV o XLXS';
            app.DistancePanel = uipanel(app.GridLayout6);
            app.DistancePanel.Title = 'Distance (m):';
            app.distance = uieditfield(app.GridLayout6, 'numeric');
            app.distance.Limits = [0 Inf];
            app.distance.ValueDisplayFormat = '%.0f';
            app.DurationPanel = uipanel(app.GridLayout6);
            app.DurationPanel.Title = 'Duration (s):';
            app.duration = uieditfield(app.GridLayout6, 'numeric');
            app.duration.Limits = [0 Inf];
            app.duration.ValueDisplayFormat = '%.0f';
            app.SpeedPanel = uipanel(app.GridLayout6);
            app.SpeedPanel.Title = 'Speed (m/s):';
            app.fileName = uilabel(app.GridLayout6);
            app.fileName.Text = '';
            app.FileLabel = uilabel(app.GridLayout6);
            app.FileLabel.Text = 'File: ';
            app.speed = uieditfield(app.GridLayout6, 'numeric');
            app.speed.Limits = [0 Inf];
            app.speed.ValueDisplayFormat = '%.0f';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.TotalTimePanel = uipanel(app.GridLayout7);
            app.TotalTimePanel.Title = 'Total Time:';
            app.StepPanel = uipanel(app.GridLayout7);
            app.StepPanel.Title = 'Step:';
            app.period = uieditfield(app.GridLayout7, 'numeric');
            app.period.Limits = [0 Inf];
            app.period.ValueDisplayFormat = '%.0f';
            app.step = uieditfield(app.GridLayout7, 'numeric');
            app.step.Limits = [0 Inf];
            app.step.ValueDisplayFormat = '%.0f';
            app.MobilityAnalysisLabel = uilabel(app.GridLayout);
            app.MobilityAnalysisLabel.Text = 'Mobility Analysis';
            app.DeploymentPanel = uipanel(app.GridLayout);
            app.DeploymentPanel.Title = 'Deployment';
            app.GridLayout8 = uigridlayout(app.DeploymentPanel);
            app.UMACheckBox = uicheckbox(app.GridLayout8);
            app.UMACheckBox.Enable = 'off';
            app.UMACheckBox.Text = 'UMA';
            app.UMACheckBox.Value = true;
            app.UMIGridCheckBox = uicheckbox(app.GridLayout8);
            app.UMIGridCheckBox.Text = 'UMI Grid';
            app.UMIHotspotCheckBox = uicheckbox(app.GridLayout8);
            app.UMIHotspotCheckBox.Text = 'UMI Hotspot';
            app.UMIBlindCheckBox = uicheckbox(app.GridLayout8);
            app.UMIBlindCheckBox.Text = 'UMI Blind';
            app.FileNetworkCellInfoCheckBox = uicheckbox(app.GridLayout);
            app.FileNetworkCellInfoCheckBox.Text = 'File Network Cell Info';
            % Show the figure after all components are created
            app.Urban5GRXUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Urban5GRXUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Urban5GRXUIFigure)
        end
    end
end